classdef ImageAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        LoadimageButton          matlab.ui.control.StateButton
        Channel1CheckBox         matlab.ui.control.CheckBox
        Channel2CheckBox         matlab.ui.control.CheckBox
        Channel3CheckBox         matlab.ui.control.CheckBox
        ThresholdVesicles        matlab.ui.control.Button
        VesiclesDropDown         matlab.ui.control.DropDown
        ColocalizationButton     matlab.ui.control.Button
        ColocalizationDropDown1  matlab.ui.control.DropDown
        ColocalizationDropDown2  matlab.ui.control.DropDown
        CellMaskCheckBox         matlab.ui.control.CheckBox
        Vesicles1CheckBox        matlab.ui.control.CheckBox
        SegmentDropDown          matlab.ui.control.DropDown
        SegmentCellLabel         matlab.ui.control.Label
        LoadMaskButton           matlab.ui.control.Button
        SegmentSlider            matlab.ui.control.Slider
        WatershedButton          matlab.ui.control.Button
        VesiclesLabel            matlab.ui.control.Label
        VesiclesMaskButton       matlab.ui.control.Button
        VesiclesSlider           matlab.ui.control.Slider
        LoadImageLabel           matlab.ui.control.Label
        AnalyzeLabel             matlab.ui.control.Label
        Vesicles2CheckBox        matlab.ui.control.CheckBox
        SegmentThresholdButton   matlab.ui.control.Button
        VesicleSizeSlider        matlab.ui.control.Slider
        LevelLabel               matlab.ui.control.Label
        SizeLabel                matlab.ui.control.Label
        SaveLabel                matlab.ui.control.Label
        SaveImageButton          matlab.ui.control.Button
        SaveDataButton           matlab.ui.control.Button
        LevelLabel_2             matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
        ZSliderLabel             matlab.ui.control.Label
        ZSlider                  matlab.ui.control.Slider
    end

    properties (Access = private)
        C1 % channel 1
        C2 % channel 2
        C3 % channel 3
        cellMask % binary image of the cell
        vesicles1 % binary image of vesicles
        vesStats1 % struct containing vesicle stats
        vesicles2 % binary image of vesicles
        vesStats2 % struct containing vesicle stats
        colocalization % colocalization value
    end

    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
           app.C1 = [];
           app.C2 = [];
           app.C3 = [];
           app.cellMask = [];
           app.vesicles1 = [];
           app.vesicles2 = [];
        end

        % Button pushed function: ColocalizationButton
        function getcolocalization(app, event)
            if(isempty(app.cell))
                msgbox('Calculate cell mask first');
            else
                if(app.ColocalizationDropDown1.Value == 'Channel 1')
                    C4 = app.C1;
                elseif(app.ColocalizationDropDown1.Value == 'Channel 2')
                    C4 = app.C2;
                else
                    C4 = app.C3;
                end
                if(app.ColocalizationDropDown2.Value == 'Channel 1')
                    C5 = app.C1;
                elseif(app.ColocalizationDropDown2.Value == 'Channel 2')
                    C5 = app.C2;
                else
                    C5 = app.C3;
                end
                app.colocalization = pearson(C4,C5,app.cellMask);
                msgbox(strcat('Pearson: ',num2str(app.colocalization,5)));
            end  
        end

        % Value changed function: LoadimageButton
        function loadimage(app, event)
            filename = uigetfile('*.tif');
            data = loadtif(filename);
            app.C1 = data{1,1};
            app.C2 = data{1,2};
            app.C3 = data{1,3};
            app.ZSlider.Limits = [1 size(app.C1,3)];
            app.showimage();
            app.LoadimageButton.Value = 0;
        end

        % Button pushed function: SegmentThresholdButton
        function cellThreshold(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.cellMask = thresholdCell(app.C1,app.SegmentSlider.Value);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.cellMask = thresholdCell(app.C2,app.SegmentSlider.Value);
                else
                    app.cellMask = thresholdCell(app.C3,app.SegmentSlider.Value);
                end
                app.showimage();
            end
        end

        % Callback function: CellMaskCheckBox, Channel1CheckBox, 
        % Channel2CheckBox, Channel3CheckBox, Vesicles1CheckBox, 
        % Vesicles2CheckBox, ZSlider
        function showimage(app, event)
            z = round(app.ZSlider.Value);
            if(app.Channel1CheckBox.Value == 1)
                R = app.C1(:,:,z);
            else
                R = app.C1(:,:,1).*0;
            end
            if(app.Channel2CheckBox.Value == 1)
                G = app.C2(:,:,z);
            else
                G = app.C2(:,:,1).*0;
            end
            if(app.Channel3CheckBox.Value == 1)
                B = app.C3(:,:,z);
            else
                B = app.C3(:,:,1).*0;
            end
            if(app.CellMaskCheckBox.Value == 1)
                if(isempty(app.cellMask))
                    msgbox('Calculate cell mask first');
                else
                    R = max(R,bwperim(app.cellMask(:,:,z)));
                    G = max(G,bwperim(app.cellMask(:,:,z)));
                    B = max(B,bwperim(app.cellMask(:,:,z)));
                end
            end
            if(app.Vesicles1CheckBox.Value == 1)
                if(isempty(app.vesicles1))
                    msgbox('Calculate vesicles 1 first');
                else
                    R = max(R,app.vesicles1(:,:,z));
                    G = max(G,app.vesicles1(:,:,z));
                    B = max(B,app.vesicles1(:,:,z));
                end
            end
            if(app.Vesicles2CheckBox.Value == 1)
                if(isempty(app.vesicles2))
                    msgbox('Calculate vesicles 2 first');
                else
                    R = max(R,app.vesicles2(:,:,z));
                    G = max(G,app.vesicles2(:,:,z));
                    B = max(B,app.vesicles2(:,:,z));
                end
            end
            image(app.UIAxes,cat(3,R,G,B));
        end

        % Button pushed function: LoadMaskButton
        function LoadMaskButtonPushed(app, event)
            filename = uigetfile('*.tif');
            data = loadtif(filename);
            app.cellMask = data{1,1};
        end

        % Button pushed function: VesiclesMaskButton
        function VesiclesMaskButtonPushed(app, event)
            filename = uigetfile('*.tif');
            data = loadtif(filename);
            app.vesicles1 = data{1,1};
        end

        % Button pushed function: ThresholdVesicles
        function ThresholdVesiclesButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                if(app.DropDown.Value == 'Vesicles 1')
                    if(app.VesiclesDropDown.Value == 'Channel 1')
                        [app.vesicles1,app.vesStats1] = getVesicles(app.C1,app.cellMask,app.VesiclesSlider.Value,app.VesicleSizeSlider.Value);
                    elseif(app.VesiclesDropDown.Value == 'Channel 2')
                        [app.vesicles1,app.vesStats1] = getVesicles(app.C2,app.cellMask,app.VesiclesSlider.Value,app.VesicleSizeSlider.Value);
                    else
                        [app.vesicles1,app.vesStats1] = getVesicles(app.C3,app.cellMask,app.VesiclesSlider.Value,app.VesicleSizeSlider.Value);
                    end
                    app.showimage();
                else
                    if(app.VesiclesDropDown.Value == 'Channel 1')
                        [app.vesicles2,app.vesStats2] = getVesicles(app.C1,app.cellMask,app.VesiclesSlider.Value,app.VesicleSizeSlider.Value);
                    elseif(app.VesiclesDropDown.Value == 'Channel 2')
                        [app.vesicles2,app.vesStats2] = getVesicles(app.C2,app.cellMask,app.VesiclesSlider.Value,app.VesicleSizeSlider.Value);
                    else
                        [app.vesicles2,app.vesStats2] = getVesicles(app.C3,app.cellMask,app.VesiclesSlider.Value,app.VesicleSizeSlider.Value);
                    end
                end 
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.LoadimageButton = uibutton(app.Panel, 'state');
            app.LoadimageButton.ValueChangedFcn = createCallbackFcn(app, @loadimage, true);
            app.LoadimageButton.Text = 'Load image';
            app.Channel1CheckBox = uicheckbox(app.Panel);
            app.Channel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @showimage, true);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBox = uicheckbox(app.Panel);
            app.Channel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @showimage, true);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBox = uicheckbox(app.Panel);
            app.Channel3CheckBox.ValueChangedFcn = createCallbackFcn(app, @showimage, true);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel3CheckBox.Value = true;
            app.ThresholdVesicles = uibutton(app.Panel, 'push');
            app.ThresholdVesicles.ButtonPushedFcn = createCallbackFcn(app, @ThresholdVesiclesButtonPushed, true);
            app.ThresholdVesicles.Text = 'Threshold';
            app.VesiclesDropDown = uidropdown(app.Panel);
            app.VesiclesDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.VesiclesDropDown.Value = 'Channel 1';
            app.ColocalizationButton = uibutton(app.Panel, 'push');
            app.ColocalizationButton.ButtonPushedFcn = createCallbackFcn(app, @getcolocalization, true);
            app.ColocalizationButton.Text = 'Co-localization';
            app.ColocalizationDropDown1 = uidropdown(app.Panel);
            app.ColocalizationDropDown1.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColocalizationDropDown1.Value = 'Channel 1';
            app.ColocalizationDropDown2 = uidropdown(app.Panel);
            app.ColocalizationDropDown2.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColocalizationDropDown2.Value = 'Channel 1';
            app.CellMaskCheckBox = uicheckbox(app.Panel);
            app.CellMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @showimage, true);
            app.CellMaskCheckBox.Text = 'Cell Mask';
            app.Vesicles1CheckBox = uicheckbox(app.Panel);
            app.Vesicles1CheckBox.ValueChangedFcn = createCallbackFcn(app, @showimage, true);
            app.Vesicles1CheckBox.Text = 'Vesicles 1';
            app.SegmentDropDown = uidropdown(app.Panel);
            app.SegmentDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.SegmentDropDown.Value = 'Channel 1';
            app.SegmentCellLabel = uilabel(app.Panel);
            app.SegmentCellLabel.Text = 'Segment Cell';
            app.LoadMaskButton = uibutton(app.Panel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMaskButtonPushed, true);
            app.LoadMaskButton.Text = 'Load Mask';
            app.SegmentSlider = uislider(app.Panel);
            app.SegmentSlider.Limits = [0 1];
            app.SegmentSlider.MinorTicks = [];
            app.SegmentSlider.Value = 0.5;
            app.WatershedButton = uibutton(app.Panel, 'push');
            app.WatershedButton.Text = 'Watershed';
            app.VesiclesLabel = uilabel(app.Panel);
            app.VesiclesLabel.Text = 'Vesicles';
            app.VesiclesMaskButton = uibutton(app.Panel, 'push');
            app.VesiclesMaskButton.ButtonPushedFcn = createCallbackFcn(app, @VesiclesMaskButtonPushed, true);
            app.VesiclesMaskButton.Text = 'Load Mask';
            app.VesiclesSlider = uislider(app.Panel);
            app.VesiclesSlider.Limits = [0 1];
            app.VesiclesSlider.MinorTicks = [];
            app.VesiclesSlider.Value = 0.5;
            app.LoadImageLabel = uilabel(app.Panel);
            app.LoadImageLabel.Text = 'Load Image';
            app.AnalyzeLabel = uilabel(app.Panel);
            app.AnalyzeLabel.Text = 'Analyze';
            app.Vesicles2CheckBox = uicheckbox(app.Panel);
            app.Vesicles2CheckBox.ValueChangedFcn = createCallbackFcn(app, @showimage, true);
            app.Vesicles2CheckBox.Text = 'Vesicles 2';
            app.SegmentThresholdButton = uibutton(app.Panel, 'push');
            app.SegmentThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @cellThreshold, true);
            app.SegmentThresholdButton.Text = 'Threshold';
            app.VesicleSizeSlider = uislider(app.Panel);
            app.VesicleSizeSlider.Limits = [0 1];
            app.VesicleSizeSlider.MinorTicks = [];
            app.VesicleSizeSlider.Value = 0.5;
            app.LevelLabel = uilabel(app.Panel);
            app.LevelLabel.Text = 'Level';
            app.SizeLabel = uilabel(app.Panel);
            app.SizeLabel.Text = 'Size';
            app.SaveLabel = uilabel(app.Panel);
            app.SaveLabel.Text = 'Save';
            app.SaveImageButton = uibutton(app.Panel, 'push');
            app.SaveImageButton.Text = 'Save Image';
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.LevelLabel_2 = uilabel(app.Panel);
            app.LevelLabel_2.Text = 'Level';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Vesicles 1', 'Vesicles 2'};
            app.DropDown.Value = 'Vesicles 1';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.MajorTicks = [];
            app.ZSlider.MajorTickLabels = {};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @showimage, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @showimage, true);
            app.ZSlider.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageAnalyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end